classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        Button_browse  matlab.ui.control.Button
        Slider         matlab.ui.control.Slider
        SliderLabel    matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        headerInfo;
        volumeData;
        voxelcoordinate_homogeneous;

        path_function = "";
        path_snapshot = "";
        path_bonescan = "";
    end
    
    methods (Access = private)
        
        function processBoneVoxel(app, threshold)
            % find indices over threshold
            idx_overthresh = find(app.volumeData>threshold);
            
            % convert indices into voxel
            voxelcoordinate = ind2sub_c_style(idx_overthresh, app.headerInfo.DimSize);
            app.voxelcoordinate_homogeneous = [voxelcoordinate'; ones(1, length(voxelcoordinate))];
            
            % get the transformation value from MHA reader
            init_t = app.headerInfo.Offset;
            init_s = diag(app.headerInfo.ElementSpacing);
            init_R = reshape(app.headerInfo.TransformMatrix, [3,3]);
            init_A = [init_R.*init_s, init_t'; 0 0 0 1];
            
            % trasform the points
            app.voxelcoordinate_homogeneous = init_A * app.voxelcoordinate_homogeneous;
        end
        
        function displayBoneVoxel(app)
            % Normalize z values to range [0, 1] for colormap mapping
            z = app.voxelcoordinate_homogeneous(3,:);
            z_normalized = (z - min(z)) / (max(z) - min(z));

            scatter3(  app.UIAxes, ...
                       app.voxelcoordinate_homogeneous(1,:), ...
                       app.voxelcoordinate_homogeneous(2,:),  ...
                       app.voxelcoordinate_homogeneous(3,:), ...
                       5, z_normalized, ...
                      'filled');

            % hold(app.UIAxes, 'on');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            path_root    = "D:\Documents\BELANDA\PhD Thesis\Code\MATLAB\amode_navigation_experiment\experiment_a";
            dir_function = "functions";
            dir_trial    = "trial_0006_Session2_02";
            
            % declare some of the important paths
            app.path_function = fullfile(path_root, dir_function);
            app.path_snapshot = fullfile(path_root, "data", dir_trial, "snapshot");
            app.path_bonescan = fullfile(path_root, "data", dir_trial, "bonescan");
            addpath(genpath(app.path_function));
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            processBoneVoxel(app, event.Value);

            % Preserve the current view
            [az, el] = view(app.UIAxes);
            %currentLimits = axis(app.UIAxes);

            displayBoneVoxel(app);

            % Restore the 3D view and axis limits
            view(app.UIAxes, az, el);
            %axis(app.UIAxes, currentLimits);
        end

        % Button pushed function: Button_browse
        function Button_browsePushed(app, event)
            % Open a file selection dialog
            [filename, filepath] = uigetfile({'VolumeOutput_*.mha', 'Sequence Files (*.mha)'; ...
                                               '*.*', 'All Files (*.*)'}, ...
                                               'Select a File', app.path_bonescan);

            % Check if the user canceled the dialog
            if isequal(filename, 0)
                disp('User canceled the file selection.');
                return;
            end

            % Display the selected file path
            fullFilePath = fullfile(filepath, filename);
            fprintf('User selected: %s\n', fullFilePath);

            % read bone scan
            reader = MHAReader(fullFilePath);
            if ~reader.readVolumeImage()            
                disp('File can not be read, somehow.');
                return;
            end

            app.headerInfo = reader.getMHAHeader();
            app.volumeData = reader.getMHAVolume();

            processBoneVoxel(app, app.Slider.Value);
            displayBoneVoxel(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SliderLabel = uilabel(app.GridLayout);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.GridLayout);
            app.Slider.Limits = [50 200];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 133.215618336887;
            app.Button_browse = uibutton(app.GridLayout, 'push');
            app.Button_browse.ButtonPushedFcn = createCallbackFcn(app, @Button_browsePushed, true);
            app.Button_browse.Icon = fullfile(pathToMLAPP, 'assets', 'icons', 'folder.png');
            app.Button_browse.Text = 'Open';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end